classdef EscherAppSlave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EscherSlaveFig                 matlab.ui.Figure
        UDPConnectionPanel             matlab.ui.container.Panel
        ListenBtn                      matlab.ui.control.Button
        SlaveIPLabel                   matlab.ui.control.Label
        SlaveIp                        matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        SlavePort                      matlab.ui.control.NumericEditField
        MasterIPLabel                  matlab.ui.control.Label
        MasterIp                       matlab.ui.control.EditField
        PortEditFieldLabel_2           matlab.ui.control.Label
        MasterPort                     matlab.ui.control.NumericEditField
        GetSlaveIPandMACBtn            matlab.ui.control.Button
        StatusEditFieldLabel           matlab.ui.control.Label
        ConnectionStatus               matlab.ui.control.EditField
        MACLabel                       matlab.ui.control.Label
        MasterMac                      matlab.ui.control.EditField
        MACLabel_2                     matlab.ui.control.Label
        SlaveMac                       matlab.ui.control.EditField
        ScreenFeaturesPanel            matlab.ui.container.Panel
        ScreendistancecmLabel          matlab.ui.control.Label
        ScreenDistance                 matlab.ui.control.NumericEditField
        WidthcmLabel                   matlab.ui.control.Label
        ScreenWidth                    matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        ScreenHeight                   matlab.ui.control.NumericEditField
        ScreenFormulaLabel             matlab.ui.control.Label
        ScreenFuncTxt                  matlab.ui.control.EditField
        LuminanceLabel                 matlab.ui.control.Label
        SynchronizationPanel           matlab.ui.container.Panel
        ard_ck                         matlab.ui.control.CheckBox
        HeightpxEditFieldLabel         matlab.ui.control.Label
        hhTxt                          matlab.ui.control.NumericEditField
        CueWidthpxEditFieldLabel       matlab.ui.control.Label
        wwTxt                          matlab.ui.control.NumericEditField
        CuepositionPanel               matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CueTopRB                       matlab.ui.control.RadioButton
        CueBotRB                       matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        CueLeftRB                      matlab.ui.control.RadioButton
        CueRightRB                     matlab.ui.control.RadioButton
        DurationofcuemsEditFieldLabel  matlab.ui.control.Label
        optDtrTimeTxt                  matlab.ui.control.NumericEditField
        CalModeRBGroup                 matlab.ui.container.ButtonGroup
        UncalibratedButton             matlab.ui.control.RadioButton
        CalibratedButton               matlab.ui.control.RadioButton
        oculusCk                       matlab.ui.control.CheckBox
        OneScreenMode_ck               matlab.ui.control.CheckBox
        SphericaldistortioncorrectionCheckBox  matlab.ui.control.CheckBox
        StandbylumEditFieldLabel       matlab.ui.control.Label
        StandbyL                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        w % pointer to the open screen
        ifi % interval between frames
        black % value of black (usually 0)
        white % value of white (usually 255)
        screenRect % (4-element vector of the screen coordinates)
        ScreenBitDepth % bit depth of the colors (usually 8)
        HermesRect % Like screenRect but for Hermes
        OnePxAngle % Angle subtended by a single pixel
        ScreenFunc % Screen function (from 0-1 value to luminance)
        
        UdpObj % The actual UDP object
        
        ListeningFlag % This is 1 when listening is active, 0 when it's off
        BusyFlag % It's 1 if the slave is busy
    end
        
    methods (Access = public)
        
        function UdpDataReceivedCallback(app,obj)
            MsgIn = fscanf(obj);
%             Remove any white-space character.
            MsgIn = regexprep(MsgIn,'\s','');
%             Then split the message, according to the separator commas.
            MsgCellArray = strsplit(MsgIn,',');
            %             The first element of the cell array contains usually the MAC
            %             address of the slave, but when the connection is started it
            %             contains the word 'start'.
            if strcmp(MsgCellArray{1},'start')
                if ~ValidateIPv4(MsgCellArray{2}) || ~ValidateMac(MsgCellArray{3})
%                     If there is no valid IP or MAC
                    return
                end
                
%                 First of all, we must ask for confirmation to accept this
%                 connection.
                selection = uiconfirm(app.EscherSlaveFig,['Accept connection from IP ',...
                    MsgCellArray{2},' and MAC ',MsgCellArray{3},'?'],'Connection confirmation');
                if strcmp(selection,'Cancel')
                    return
                end
%                 If the connection is accepted, we can go on.
%                 Close any open screen.
                CloseScreen
%                 The second and third word contain the master IP and MAC
%                 respectively. The fourth is the master port.
                app.MasterIp.Value = MsgCellArray{2};
                app.MasterMac.Value = MsgCellArray{3};
                app.MasterPort.Value = str2double(MsgCellArray{4});
                
%                 The UDP object must be closed and then modified.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
%                 Set the remote (master) IP and port.
                app.UdpObj.RemoteHost = app.MasterIp.Value;
                app.UdpObj.RemotePort = app.MasterPort.Value;
%                 Then open the connection again.
                fopen(app.UdpObj);
                readasync(app.UdpObj)
                app.UdpObj.ReadAsyncMode = 'continuous';
                
%                 The connection is set, so the buttons must be changed
%                 accordingly.
                app.ConnectionStatus.Value = 'Connected.';
                
%                 Send a confirmation message, containing the word 'start', the IP and MAC addresses.
                msg=strjoin([{'start'},{app.SlaveIp.Value},{app.SlaveMac.Value},...
                    {num2str(app.SlavePort.Value)},MsgCellArray(5)],',');
                fprintf(app.UdpObj,msg);
                                
            elseif strcmp(MsgCellArray{1},app.MasterMac.Value)
                switch MsgCellArray{2}
% The second word in the message contains the instruction.
                    case 'screen'
% The screen was opened or closed successfully, so the parameters of the 
% screen are returned.
% 0=screen has to be closed
% 1=screen has to be opened
% 2=screen parameters have to be refreshed. Can be used to check the 
% connection.
                        if strcmp(MsgCellArray{3},'0')
% Close the screen
                            CloseScreen
                        else
%  First of all, set all parameters.
                            app.ScreenDistance.Value=str2double(MsgCellArray{4});
                            app.ScreenWidth.Value=str2double(MsgCellArray{5});
                            app.ScreenHeight.Value=str2double(MsgCellArray{6});
                            app.CalibratedButton.Value=str2double(MsgCellArray{7});
                            app.ScreenFuncTxt.Value=MsgCellArray{8};
                            app.ard_ck.Value=str2double(MsgCellArray{9});
                            app.wwTxt.Value=str2double(MsgCellArray{10});
                            app.hhTxt.Value=str2double(MsgCellArray{11});
                            app.optDtrTimeTxt.Value=str2double(MsgCellArray{12});
                            app.CueTopRB.Value=str2double(MsgCellArray{13});
                            app.CueLeftRB.Value=str2double(MsgCellArray{14});
                            app.OneScreenMode_ck.Value=str2double(MsgCellArray{15});
                            app.oculusCk.Value=str2double(MsgCellArray{16});
                            app.SphericaldistortioncorrectionCheckBox.Value=str2double(MsgCellArray{17});
                            if strcmp(MsgCellArray{3},'1')
% Only for when the screen has to be opened.
% Close any open screen.
                                CloseScreen
% Open the screen.
                                OpenScreen(app, app.StandbyL.Value)
                            else
% Only for when the screen has to be refreshed.
                                HermesRectCalc(app)
                                PixelAngleCalc(app)
                            end
% The screen function (from 0-1 value to luminance, has to be saved into 
% the variable.
                            app.ScreenFunc = app.ScreenFuncTxt.Value;
% Sends the dimensions of the screen to the master.
                            msg=strjoin([{app.SlaveMac.Value},{'screenok'},{num2str(app.screenRect(3))},...
                                {num2str(app.screenRect(4))},{num2str(app.OnePxAngle)}],',');
                            fprintf(app.UdpObj,msg);
                        end
%                     case 'flash'
%                         FlashStruct.Blumi=str2double(MsgCellArray{3});
%                         FlashStruct.Slumi=str2double(MsgCellArray{4});
%                         FlashStruct.Bt=str2double(MsgCellArray{5});
%                         FlashStruct.St=str2double(MsgCellArray{6});
%                         FlashStruct.p=str2double(MsgCellArray{7});
%                         FlashStruct.n=str2double(MsgCellArray{8});
%                         FlashStruct.bsl_check=str2double(MsgCellArray{9});
%                         FlashStruct.OneScreenFlag=str2double(MsgCellArray{10});
%                         FlashStruct.CalibrationFlag=str2double(MsgCellArray{11});
%                         FlashStruct.ard_flag=str2double(MsgCellArray{12});
%                         FlashStruct.baseline_ttl=str2double(MsgCellArray{13});
% % Send a message to confirm that the stimulus is ongoing and that it must 
% % wait for a number of seconds.
%                         TotalTime=2*FlashStruct.Bt+FlashStruct.p*(FlashStruct.n-1);
%                         msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
%                         fprintf(app.UdpObj,msg);
%                         
% % Call the Flash function
%                         GoFlash(app,FlashStruct)
%                         
%                     case 'check'
%                         CheckStruct.Blumi=str2double(MsgCellArray{3});
%                         CheckStruct.Slumi=str2double(MsgCellArray{4});
%                         CheckStruct.sF=str2double(MsgCellArray{5});
%                         CheckStruct.Bt=str2double(MsgCellArray{6});
%                         CheckStruct.p=str2double(MsgCellArray{7});
%                         CheckStruct.n=str2double(MsgCellArray{8});
%                         CheckStruct.baseline_ttl=str2double(MsgCellArray{9});
%                         CheckStruct.OneScreenFlag=str2double(MsgCellArray{10});
%                         CheckStruct.CalibrationFlag=str2double(MsgCellArray{11});
%                         CheckStruct.ard_flag=str2double(MsgCellArray{12});
%                         CheckStruct.baseline_ttl=str2double(MsgCellArray{13});
%                         
% % Send a message to confirm that the stimulus is ongoing and that it must 
% % wait for a number of seconds.
%                         TotalTime=2*CheckStruct.Bt+2*CheckStruct.p*CheckStruct.n;
%                         msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
%                         fprintf(app.UdpObj,msg);
%                         
% % Call the Flash function
%                         GoCheck(app,CheckStruct)
%                     case 'grid'
%                         GridStruct.Glumi = str2double(MsgCellArray{3}); % the average luminance
%                         GridStruct.inc = str2double(MsgCellArray{4}); % the increment of luminance
%                         GridStruct.sF = str2double(MsgCellArray{5}); % spatial frequency of the stimulus (in cycles/degree)
%                         GridStruct.GridType = str2double(MsgCellArray{6}); % 1 means sinusoidal, 0 is for square-wave (bar)
%                         GridStruct.Angle = str2double(MsgCellArray{7}); % angle of the stimulus
%                         GridStruct.dS = str2double(MsgCellArray{8}); % drifting speed of the stimulus in cycles/second.
%                         GridStruct.MaskFlag = str2double(MsgCellArray{9}); % 1 means that a gaussian mask is applied.
%                         GridStruct.GaussSize = str2double(MsgCellArray{10}); % size of gaussian mask
%                         GridStruct.Bt = str2double(MsgCellArray{11}); % initial delay
%                         GridStruct.PreG = str2double(MsgCellArray{12}); % pre-grid duration
%                         GridStruct.GridT = str2double(MsgCellArray{13}); % grid duration
%                         GridStruct.PostG = str2double(MsgCellArray{14}); % post-grid duration
%                         GridStruct.n = str2double(MsgCellArray{15}); % number of repetitions of the stimulus
%                         GridStruct.dark_bsl = str2double(MsgCellArray{16}); % number of repetitions of the stimulus
%                         GridStruct.PcoWhileStimFlag = str2double(MsgCellArray{17}); % if PCO records while stimulating
%                         GridStruct.OneScreenFlag=str2double(MsgCellArray{18});
%                         GridStruct.CalibrationFlag=str2double(MsgCellArray{19});
%                         GridStruct.ard_flag=str2double(MsgCellArray{20});
%                         GridStruct.baseline_ttl=str2double(MsgCellArray{21});
%                         
% % Send a message to confirm that the stimulus is ongoing and that it must 
% % wait for a number of seconds.
%                         TotalTime=2*GridStruct.Bt+(GridStruct.PreG+GridStruct.GridT+GridStruct.PostG)*GridStruct.n;
%                         msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime)],',');
%                         fprintf(app.UdpObj,msg);
%                         
% % Call the Flash function
%                         GoGrid(app,GridStruct)
%                     
%                     case 'retinotopy'                       
%                         % ParameterVector = [Blumi,Slumi,Glumi,sF,Bt,p,Angle,RectangleSizeDeg,dS,PostG,n];
%                         % GAB: MsgCellArray{1} and {2} contain the address
%                         % and the type the stimulus
%                         ParameterVector=cellfun(@str2double,MsgCellArray(3:end),'UniformOutput',false)';
%                         
% % Additional flags to be passed to IntrinsicPresent.
%                         oculusFlag = app.oculusCk.Value;
%                         optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
%                         ParameterVector = vertcat(ParameterVector,num2cell(...
%                             [oculusFlag;optDtrTime]));
%                         
% % Send a message to confirm that the stimulus is ongoing and that it must 
% % wait for a number of seconds.
% % Here, we need to have some parameters to calculate this.
% %                       TotalTime=Bt+(TravelTime+PostG)*n;
%                         Bt = ParameterVector{5};
%                         Angle = ParameterVector{7};
%                         dS = ParameterVector{9};
%                         PostG = ParameterVector{10};
%                         n = ParameterVector{11};
%                         if Angle==0 || Angle==180
%                         % vertical bar
%                             TravelDistance = app.screenRect(3);
%                             dSPx = dS/app.OnePxAngle;
%                         else
%                         % horizontal
%                             TravelDistance = app.screenRect(4);
%                             dSPx = dS/app.OnePxAngle;
%                         end
%                         TravelTime = TravelDistance/dSPx;
%                         TotalTime=Bt+(TravelTime+2*PostG)*n;
%                         
%                         msg=strjoin([{app.SlaveMac.Value},'wait',num2str(TotalTime,'%.3f')],',');
%                         fprintf(app.UdpObj,msg);
%                         
%                         timestamps = RetinotopyPresent(app,ParameterVector);
%                         
%             %             Timestamps are stored in the log file as a character vector,
%             %             in which timestamps are separated by a semi-colon.
%             %             Timestamps are saved with 6 significant digits after decimal point (microseconds).
%                         msg=strjoin([{app.SlaveMac.Value},{'stimok'},{strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
%                         fprintf(app.UdpObj,msg);
                        
                    case 'background'
%                         A single screen projection.
                        ard_flag = app.ard_ck.Value;
                        Blumi = str2double(MsgCellArray{3});
                        if app.CalibratedButton.Value
                            Blumi = Lumi2Escher(Blumi,app.white,app.ScreenFunc);
                        end
                        Blumi = app.white*Blumi;
                        if ard_flag
                            HermesRectCalc(app)
                            BaselineColor = cast([[Blumi;Blumi;Blumi], [0;0;0]], app.ScreenBitDepth);
                            BaselineScreen = [app.screenRect; app.HermesRect]';
                            
                            %     This is commented because in flashes, the optical DTR
                            %     duration is the same as the flash duration
                            %     optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
                        else
                            BaselineColor = cast(Blumi, app.ScreenBitDepth);
                           	BaselineScreen = app.screenRect;
                        end
                        Screen('FillRect', app.w, BaselineColor, BaselineScreen); % paints the rectangle (entire screen)
                        Screen('Flip', app.w);
                        
                        msg=strjoin([{app.SlaveMac.Value},{'ok'}],',');
                        fprintf(app.UdpObj,msg);
                        
                    case 'calibration'
                        switch MsgCellArray{3}
                            case 'b'
%                             If the calibration is 'b', the black
%                             calibration is performed (the screen becomes
%                             all white apart from the small Hermes patch).
%                             If the calibration is 'w', the white
%                             calibration is performed and the screen turns
%                             all black apart for the Hermes patch.
                            ScreenColor=app.white;
                            HermesColor=0;
                            case 'w'
                            ScreenColor=0;
                            HermesColor=app.white;
                        end
                        HermesRectCalc(app)
                        CalibColor = cast([[ScreenColor;ScreenColor;ScreenColor], [HermesColor;HermesColor;HermesColor]], app.ScreenBitDepth);
                        CalibScreen = [app.screenRect; app.HermesRect]';
                        
                        Screen('FillRect', app.w, CalibColor, CalibScreen);
                        Screen('Flip', app.w);
                        
                        msg=strjoin([{app.SlaveMac.Value},{'calibrationok'},MsgCellArray(3)],',');
                        fprintf(app.UdpObj,msg);
                        
                    case 'done'
                        %                         A stimulation is over, another one can be
                        %                         started.
                        
                    case 'dots'
                        
                    otherwise % all visual stimuli go here
                        % MsgCellArray{2} is a string specifying the stimulus
                        % type (eg: 'flash').
                        % MsgCellArray elements from 3 to end contain the
                        % parameters of the visual stimulus.
                        paramVect = cellfun(@str2double,MsgCellArray(3:end));
                        callVisStim(app,MsgCellArray{2},num2cell(paramVect))
                end
            end
        end
        
%         function GoFlash(app,FlashStruct)
%             Blumi = FlashStruct.Blumi;
%             Slumi = FlashStruct.Slumi;
%             Bt = FlashStruct.Bt;
%             St = FlashStruct.St;
%             p = FlashStruct.p;
%             n = FlashStruct.n;
%             bsl_check = FlashStruct.bsl_check;
%             OneScreenFlag = FlashStruct.OneScreenFlag;
%             CalibrationFlag = FlashStruct.CalibrationFlag;
%             oculusFlag = 0; % useless for flashes
%             ard_flag = FlashStruct.ard_flag;
%             baseline_ttl = FlashStruct.baseline_ttl;
%             optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
%             ParameterVector = num2cell([Blumi,Slumi,Bt,St,p,n,bsl_check,OneScreenFlag,CalibrationFlag,ard_flag,baseline_ttl,oculusFlag,optDtrTime]);
% %             Calling the function that presents the visual stimulus.
%             timestamps = FlashPresent(app,ParameterVector);
%             
%             try
%                 msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
%                     {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
%                 fprintf(app.UdpObj,msg);
%             catch
%                 msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
%                     {'0'}],',');
%                 fprintf(app.UdpObj,msg);
%             end
%         end
%         
%         function GoCheck(app,CheckStruct)
%             Blumi = CheckStruct.Blumi; % the "black" square luminance
%             Slumi = CheckStruct.Slumi; % the stimulus luminance ("white" squares)
%             sF = CheckStruct.sF; % spatial frequency of each square (in cycle/degree)
%             Bt = CheckStruct.Bt; % initial delay
%             p = CheckStruct.p; % hemi-period. p is the time between a reversal and the next (2 reversals = 1 period)
%             n = CheckStruct.n; % The number of repetitions (number of full periods, each full stimulation is 2 reversals)
% 
% %             Additional parameters
%             OneScreenFlag = app.OneScreenMode_ck.Value;
%             CalibrationFlag = app.CalibratedButton.Value;
%             ard_flag = app.ard_ck.Value;
%             baseline_ttl = CheckStruct.baseline_ttl;
%             oculusFlag = app.oculusCk.Value;
%             optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
%             
%             ParameterVector = num2cell([Blumi,Slumi,sF,Bt,p,n,OneScreenFlag,CalibrationFlag,ard_flag,baseline_ttl,oculusFlag,optDtrTime]);
%             timestamps = CheckPresent(app,ParameterVector);
%             try
%                 msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
%                     {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
%                 fprintf(app.UdpObj,msg);
%             catch
%                 msg=strjoin([{app.SlaveMac.Value},{'stimok'},...
%                     {'0'}],',');
%                 fprintf(app.UdpObj,msg);
%             end
%         end
%         
%         function GoGrid(app,GridStruct)
%             % The two luminance values we need
%             Glumi = GridStruct.Glumi; % the average luminance
%             inc = GridStruct.inc; % the increment of luminance
% %             Other values needed
%             sF = GridStruct.sF; % spatial frequency of the stimulus (in cycles/degree)
%             gridType = GridStruct.GridType; % 1 means sinusoidal, 0 is for square-wave (bar)
%             Angle = GridStruct.Angle; % angle of the stimulus. 0 means vertical bars.
%             dS = GridStruct.dS; % drifting speed of the stimulus in cycles/second.
%             MaskFlag = GridStruct.MaskFlag; % 1 means that a gaussian mask is applied.
%             GaussSize = GridStruct.GaussSize; % size of gaussian mask
%             Bt = GridStruct.Bt; % initial delay
%             PreG = GridStruct.PreG; % pre-grid duration
%             GridT = GridStruct.GridT; % grid duration
%             PostG = GridStruct.PostG; % post-grid duration
%             n = GridStruct.n; % number of repetitions of the stimulus
%             dark_bsl = GridStruct.dark_bsl; %boolean. If true, use standby luminance as basline
%             PcoWhileStimFlag = GridStruct.PcoWhileStimFlag;
%             
%             OneScreenFlag = app.OneScreenMode_ck.Value;
%             CalibrationFlag = app.CalibratedButton.Value;
%             ard_flag = app.ard_ck.Value;
%             baseline_ttl = GridStruct.baseline_ttl;
%             oculusFlag = app.oculusCk.Value;
%             optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
%             
%             ParameterVector = num2cell([Glumi,inc,sF,gridType,Angle,dS,...
%                 MaskFlag,GaussSize,Bt,PreG,GridT,PostG,n,dark_bsl,...
%                 PcoWhileStimFlag,OneScreenFlag,CalibrationFlag,...
%                 ard_flag,baseline_ttl,oculusFlag,optDtrTime]);
%             timestamps = GridPresent(app,ParameterVector);
%             
% %             Timestamps are stored in the log file as a character vector,
% %             in which timestamps are separated by a semi-colon.
% %             Timestamps are saved with 6 significant digits after decimal point (microseconds).
%             msg=strjoin([{app.SlaveMac.Value},{'stimok'},{strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
%             fprintf(app.UdpObj,msg);
%         end
        
        function PixelAngleCalc(app)
% Compute angular size of single pixel. This is the angle subtended by a 
% single pixel which is at the center of the screen.

% Number of pixels in the screen width.
            Wpx = app.screenRect(3);
% Screen width in centimeters.
            ScWidth = app.ScreenWidth.Value;
% Distance of the screen in centimeters.
            ScDist = app.ScreenDistance.Value;
% The angle of a single pixel is returned in degrees.
            app.OnePxAngle = 2*atand(ScWidth/Wpx/2/ScDist);
        end
        
        function HermesRectCalc(app)
            sR = app.screenRect;
            ww = app.wwTxt.Value; %width of the optical DTR
            hh = app.hhTxt.Value; %height of the optical DTR
            %             The optical DTR must be placed according to the selection
            %             of the position
            % position of DtrRect (x0, y0, x4, y4)
            
            app.HermesRect = [app.CueRightRB.Value*(sR(3)-ww),...
                app.CueBotRB.Value*(sR(4)-hh),...
                sR(3)-app.CueLeftRB.Value*(sR(3)-ww),...
                sR(4)-app.CueTopRB.Value*(sR(4)-hh)];
        end
        
        function callVisStim(app,stim_type_str,ParameterVector)
            
            oculusFlag = app.oculusCk.Value;
            optDtrTime = app.optDtrTimeTxt.Value/1000; % optical DTR duration in s
            %                 ParameterVector = num2cell([ParameterVector,OneScreenFlag,CalibrationFlag,ard_flag]);
            ParameterVector = [ParameterVector,{oculusFlag},{optDtrTime}]; % also add oculusFlag and optDtrTime parameters
            %             Calling the function that presents the visual stimulus.
            switch stim_type_str
                case 'flash'
                    [timestamps, stopped] = FlashPresent(app,ParameterVector);
                case 'grid'
                    [timestamps, stopped] = GridPresent(app,ParameterVector);
                case 'dots'
                    [timestamps, stopped] = DotsPresent(app,ParameterVector);
                case 'check'
                    [timestamps, stopped] = CheckPresent(app,ParameterVector);
                case 'rec field'
                    [order, timestamps, stopped] = ReceptiveFieldPresent(app,ParameterVector);
                    r = app.screenRect([3,4]);
                    n = ParameterVector(6);
                    nrows = ParameterVector(7);
                    ncols = ParameterVector(8);
                    save(app.CurrentLogStruct.logName,'n','nrows','ncols','r','order')
                case 'retinotopy'
                    [timestamps, stopped] = RetinotopyPresent(app,ParameterVector);
            end
            
            if stopped
                txt = 'stiminterrupted';
            else
                txt = 'stimok';
            end
            
            try
                msg=strjoin([{app.SlaveMac.Value},{txt},...
                    {strjoin(arrayfun(@(x) num2str(x,'%.6f'),timestamps,'un' ,0),';')}],',');
                fprintf(app.UdpObj,msg);
            catch
                % if sending the udp message returns an error, try again
                % without timestamps
                msg=strjoin([{app.SlaveMac.Value},{txt},...
                    {'0'}],',');
                fprintf(app.UdpObj,msg);
            end
        end
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             Flag initialization.
            app.ListeningFlag = 0;
            
%             UDP object initialization.
            CloseAllUdp
            app.UdpObj = udp('127.0.0.1','LocalPort',app.SlavePort.Value);
            
%             Try to get the IP automatically
            GetSlaveIPandMACBtnPushed(app,[])
%             Then starts listening for a UDP message.
            ListenBtnButtonPushed(app, [])
        end

        % Button pushed function: ListenBtn
        function ListenBtnButtonPushed(app, event)
            if ~app.ListeningFlag
%                 If listening was not active, the button starts it.
                if strcmp(app.SlaveIp.Value,'0.0.0.0')||isempty(app.SlaveMac.Value)
                    uialert(app.EscherSlaveFig,'Invalid addresses!','Error')
                    return
                end
%                 Create a read-only UDP object first. When the start
%                 message will be read, then the UDP object will be
%                 modified to be able to write back.
                try
                    app.UdpObj.RemoteHost = '127.0.0.1';
                    app.UdpObj.LocalPort = app.SlavePort.Value;
                    fopen(app.UdpObj);
                catch ERR
%                     If fails opening a UDP connection, close all UDP
%                     connections.
                    CloseAllUdp
                    app.UdpObj = udp('127.0.0.1','LocalPort',app.SlavePort.Value);
                    fopen(app.UdpObj);
                end
%                 Sets the callback function for when data is received.
                app.UdpObj.DatagramReceivedFcn = @(obj,~) UdpDataReceivedCallback(app,obj);
%                 Starts reading.
                readasync(app.UdpObj)
                app.UdpObj.ReadAsyncMode = 'continuous';

                app.ListenBtn.Text = 'Stop Listening';
                app.ListeningFlag = 1;
%                 Disable the three boxes for slave connection parameters.
                set([app.SlaveIp,app.SlaveMac,app.SlavePort],'Editable',0)
                app.GetSlaveIPandMACBtn.Enable = 0;
            else
%                 If the slave was already listening, pressing the button
%                 again stops it.
                stopasync(app.UdpObj)
                fclose(app.UdpObj);
                app.ListenBtn.Text = 'Start Listening';
%                 Re-enable the three boxes for slave connection parameters.
                set([app.SlaveIp,app.SlaveMac,app.SlavePort],'Editable',1)
                app.GetSlaveIPandMACBtn.Enable = 1;
                app.ListeningFlag = 0;
            end
        end

        % Button pushed function: GetSlaveIPandMACBtn
        function GetSlaveIPandMACBtnPushed(app, event)
            ConnParams = GetConnParams;
            app.SlaveIp.Value = ConnParams.IP;
            app.SlaveMac.Value = ConnParams.MAC;
        end

        % Close request function: EscherSlaveFig
        function EscherSlaveFigCloseRequest(app, event)
            try
                CloseAllUdp
            catch ME
%                 warning('off','backtrace') % maybe not necessary
                warning(ME.message)
            end
            CloseScreen
            delete(app)
        end

        % Value changed function: SlaveIp
        function SlaveIpValueChanged(app, event)
            LogicValue = ValidateIPv4(app.SlaveIp.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveIp.Value = event.OldValue;
            end
        end

        % Value changed function: SlaveMac
        function SlaveMacValueChanged(app, event)
            LogicValue = ValidateMac(app.SlaveMac.Value);
            if ~LogicValue
                uialert(app.EscherSlaveFig,'Invalid IP address!','Error')
                app.SlaveMac.Value = event.OldValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EscherSlaveFig = uifigure('Visible', 'off');
            app.EscherSlaveFig.Name = 'EscherSlave';
            app.EscherSlaveFig.CloseRequestFcn = createCallbackFcn(app, @EscherSlaveFigCloseRequest, true);
            app.UDPConnectionPanel = uipanel(app.EscherSlaveFig);
            app.UDPConnectionPanel.Title = 'UDP Connection';
            app.ListenBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.ListenBtn.ButtonPushedFcn = createCallbackFcn(app, @ListenBtnButtonPushed, true);
            app.ListenBtn.Tooltip = {'Start listening for any incoming UDP message on the selected port.'};
            app.ListenBtn.Text = 'Start Listening';
            app.SlaveIPLabel = uilabel(app.UDPConnectionPanel);
            app.SlaveIPLabel.Text = 'Slave IP:';
            app.SlaveIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveIp.ValueChangedFcn = createCallbackFcn(app, @SlaveIpValueChanged, true);
            app.SlaveIp.Tooltip = {'IP address of the slave (this app).'};
            app.SlaveIp.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.SlavePort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.SlavePort.Limits = [1023 65535];
            app.SlavePort.RoundFractionalValues = 'on';
            app.SlavePort.ValueDisplayFormat = '%8.5g';
            app.SlavePort.Tooltip = {'Port for the UDP connection on the slave side. Default: 8866.'};
            app.SlavePort.Value = 8866;
            app.MasterIPLabel = uilabel(app.UDPConnectionPanel);
            app.MasterIPLabel.Text = 'Master IP:';
            app.MasterIp = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterIp.Editable = 'off';
            app.MasterIp.Tooltip = {'IP address of the master app. It is going to be given by the master when it starts the connection.'};
            app.MasterIp.Value = '0.0.0.0';
            app.PortEditFieldLabel_2 = uilabel(app.UDPConnectionPanel);
            app.PortEditFieldLabel_2.Text = 'Port:';
            app.MasterPort = uieditfield(app.UDPConnectionPanel, 'numeric');
            app.MasterPort.Limits = [1023 65535];
            app.MasterPort.RoundFractionalValues = 'on';
            app.MasterPort.ValueDisplayFormat = '%8.5g';
            app.MasterPort.Editable = 'off';
            app.MasterPort.Tooltip = {'Port for the UDP connection on the master side. Default: 8844.'};
            app.MasterPort.Value = 8844;
            app.GetSlaveIPandMACBtn = uibutton(app.UDPConnectionPanel, 'push');
            app.GetSlaveIPandMACBtn.ButtonPushedFcn = createCallbackFcn(app, @GetSlaveIPandMACBtnPushed, true);
            app.GetSlaveIPandMACBtn.Tooltip = {'Get the IP of this computer (slave).'};
            app.GetSlaveIPandMACBtn.Text = 'Get Slave IP and MAC';
            app.StatusEditFieldLabel = uilabel(app.UDPConnectionPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.ConnectionStatus = uieditfield(app.UDPConnectionPanel, 'text');
            app.ConnectionStatus.Editable = 'off';
            app.ConnectionStatus.Value = 'Disconnected.';
            app.MACLabel = uilabel(app.UDPConnectionPanel);
            app.MACLabel.Text = 'MAC:';
            app.MasterMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.MasterMac.Editable = 'off';
            app.MasterMac.Tooltip = {'Physical address of the master. It is going to be given by the master when it starts the connection.'};
            app.MasterMac.Value = '000000000000';
            app.MACLabel_2 = uilabel(app.UDPConnectionPanel);
            app.MACLabel_2.Text = 'MAC:';
            app.SlaveMac = uieditfield(app.UDPConnectionPanel, 'text');
            app.SlaveMac.ValueChangedFcn = createCallbackFcn(app, @SlaveMacValueChanged, true);
            app.SlaveMac.Tooltip = {'Physical address of the slave (this app).'};
            app.SlaveMac.Value = '000000000000';
            app.ScreenFeaturesPanel = uipanel(app.EscherSlaveFig);
            app.ScreenFeaturesPanel.Title = 'Screen Features';
            app.ScreendistancecmLabel = uilabel(app.ScreenFeaturesPanel);
            app.ScreendistancecmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancecmLabel.Text = 'Screen distance (cm):';
            app.ScreenDistance = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenDistance.Limits = [0 Inf];
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.Editable = 'off';
            app.WidthcmLabel = uilabel(app.ScreenFeaturesPanel);
            app.WidthcmLabel.Text = 'Width (cm)';
            app.ScreenWidth = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenWidth.Editable = 'off';
            app.HeightcmEditFieldLabel = uilabel(app.ScreenFeaturesPanel);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.ScreenHeight = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            app.ScreenHeight.Editable = 'off';
            app.ScreenFormulaLabel = uilabel(app.ScreenFeaturesPanel);
            app.ScreenFormulaLabel.Text = {'Screen '; 'Formula:'};
            app.ScreenFuncTxt = uieditfield(app.ScreenFeaturesPanel, 'text');
            app.ScreenFuncTxt.Editable = 'off';
            app.LuminanceLabel = uilabel(app.ScreenFeaturesPanel);
            app.LuminanceLabel.Text = 'Luminance=';
            app.SynchronizationPanel = uipanel(app.ScreenFeaturesPanel);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ard_ck = uicheckbox(app.SynchronizationPanel);
            app.ard_ck.Enable = 'off';
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.HeightpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.HeightpxEditFieldLabel.Text = 'Height (px)';
            app.hhTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.hhTxt.Editable = 'off';
            app.CueWidthpxEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.CueWidthpxEditFieldLabel.Text = 'Cue Width (px)';
            app.wwTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.wwTxt.Editable = 'off';
            app.CuepositionPanel = uipanel(app.SynchronizationPanel);
            app.CuepositionPanel.Title = 'Cue position';
            app.ButtonGroup = uibuttongroup(app.CuepositionPanel);
            app.CueTopRB = uiradiobutton(app.ButtonGroup);
            app.CueTopRB.Enable = 'off';
            app.CueTopRB.Text = 'Top';
            app.CueBotRB = uiradiobutton(app.ButtonGroup);
            app.CueBotRB.Enable = 'off';
            app.CueBotRB.Text = 'Bottom';
            app.CueBotRB.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.CuepositionPanel);
            app.CueLeftRB = uiradiobutton(app.ButtonGroup_2);
            app.CueLeftRB.Enable = 'off';
            app.CueLeftRB.Text = 'Left';
            app.CueRightRB = uiradiobutton(app.ButtonGroup_2);
            app.CueRightRB.Enable = 'off';
            app.CueRightRB.Text = 'Right';
            app.CueRightRB.Value = true;
            app.DurationofcuemsEditFieldLabel = uilabel(app.SynchronizationPanel);
            app.DurationofcuemsEditFieldLabel.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.SynchronizationPanel, 'numeric');
            app.optDtrTimeTxt.Limits = [0 Inf];
            app.optDtrTimeTxt.Editable = 'off';
            app.CalModeRBGroup = uibuttongroup(app.ScreenFeaturesPanel);
            app.CalModeRBGroup.Title = 'Mode:';
            app.UncalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.UncalibratedButton.Enable = 'off';
            app.UncalibratedButton.Text = 'Uncalibrated';
            app.CalibratedButton = uiradiobutton(app.CalModeRBGroup);
            app.CalibratedButton.Enable = 'off';
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibratedButton.Value = true;
            app.oculusCk = uicheckbox(app.ScreenFeaturesPanel);
            app.oculusCk.Enable = 'off';
            app.oculusCk.Text = 'VR headset';
            app.OneScreenMode_ck = uicheckbox(app.ScreenFeaturesPanel);
            app.OneScreenMode_ck.Enable = 'off';
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.SphericaldistortioncorrectionCheckBox = uicheckbox(app.ScreenFeaturesPanel);
            app.SphericaldistortioncorrectionCheckBox.Enable = 'off';
            app.SphericaldistortioncorrectionCheckBox.Text = 'Apply distortion';
            app.StandbylumEditFieldLabel = uilabel(app.ScreenFeaturesPanel);
            app.StandbylumEditFieldLabel.Text = 'Standby lum';
            app.StandbyL = uieditfield(app.ScreenFeaturesPanel, 'numeric');
            % Show the figure after all components are created
            app.EscherSlaveFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherAppSlave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EscherSlaveFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EscherSlaveFig)
        end
    end
end